classdef LLC_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AppWindow               matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Input                   matlab.ui.container.Tab
        DSsLabel                matlab.ui.control.Label
        Vin_minVEditFieldLabel  matlab.ui.control.Label
        VinminEditField         matlab.ui.control.NumericEditField
        Vin_maxVEditFieldLabel  matlab.ui.control.Label
        VinmaxEditField         matlab.ui.control.NumericEditField
        Vout_1VLabel            matlab.ui.control.Label
        Vo1EditField            matlab.ui.control.NumericEditField
        Vout_2VLabel            matlab.ui.control.Label
        Vo2EditField            matlab.ui.control.NumericEditField
        Vout_3VLabel            matlab.ui.control.Label
        Vo3EditField            matlab.ui.control.NumericEditField
        Vout_4VLabel            matlab.ui.control.Label
        Vo4EditField            matlab.ui.control.NumericEditField
        Pout_1WLabel            matlab.ui.control.Label
        Po1EditField            matlab.ui.control.NumericEditField
        Pout_2WLabel            matlab.ui.control.Label
        Po2EditField            matlab.ui.control.NumericEditField
        Pout_3WLabel            matlab.ui.control.Label
        Po3EditField            matlab.ui.control.NumericEditField
        Pout_4WLabel            matlab.ui.control.Label
        Po4EditField            matlab.ui.control.NumericEditField
        DSAxes                  matlab.ui.control.UIAxes
        btnPlotDS               matlab.ui.control.Button
        NoteLabel               matlab.ui.control.Label
        NoteLabel_2             matlab.ui.control.Label
        NoteLabel_3             matlab.ui.control.Label
        NoteLabel_4             matlab.ui.control.Label
        Vout_minVLabel          matlab.ui.control.Label
        VoutminEditField        matlab.ui.control.NumericEditField
        Vout_maxVLabel          matlab.ui.control.Label
        VoutmaxEditField        matlab.ui.control.NumericEditField
        Vin_1VLabel             matlab.ui.control.Label
        Vin1EditField           matlab.ui.control.NumericEditField
        Vin_2VLabel             matlab.ui.control.Label
        Vin2EditField           matlab.ui.control.NumericEditField
        Vin_3VLabel             matlab.ui.control.Label
        Vin3EditField           matlab.ui.control.NumericEditField
        Vin_4VLabel             matlab.ui.control.Label
        Vin4EditField           matlab.ui.control.NumericEditField
        ResTankTab              matlab.ui.container.Tab
        IPsLabel                matlab.ui.control.Label
        maxfskHzLabel           matlab.ui.control.Label
        FsmaxEditField          matlab.ui.control.NumericEditField
        minfskHzLabel           matlab.ui.control.Label
        FsminEditField          matlab.ui.control.NumericEditField
        Ln_preLabel             matlab.ui.control.Label
        LnPreEditField          matlab.ui.control.NumericEditField
        Qe_preLabel             matlab.ui.control.Label
        QePreEditField          matlab.ui.control.NumericEditField
        RTPsLabel               matlab.ui.control.Label
        turnsratioLabel         matlab.ui.control.Label
        TREditField             matlab.ui.control.NumericEditField
        btnCal                  matlab.ui.control.Button
        frkHzLabel              matlab.ui.control.Label
        frEditField             matlab.ui.control.NumericEditField
        CrnFLabel               matlab.ui.control.Label
        CrnFEditField           matlab.ui.control.NumericEditField
        LruHLabel               matlab.ui.control.Label
        LruHEditField           matlab.ui.control.NumericEditField
        LmuHEditFieldLabel      matlab.ui.control.Label
        LmuHEditField           matlab.ui.control.NumericEditField
        CalOptionDropDownLabel  matlab.ui.control.Label
        CalOptionDD             matlab.ui.control.DropDown
        OP1GCAxes               matlab.ui.control.UIAxes
        OP2GCAxes               matlab.ui.control.UIAxes
        OP3GCAxes               matlab.ui.control.UIAxes
        OP4GCAxes               matlab.ui.control.UIAxes
        GainCurveTab            matlab.ui.container.Tab
        btnPlotGC               matlab.ui.control.Button
        GainCurAxes             matlab.ui.control.UIAxes
        Qe_maxEditFieldLabel    matlab.ui.control.Label
        QemaxEditField          matlab.ui.control.NumericEditField
        Rac_minLabel            matlab.ui.control.Label
        RacminEditField         matlab.ui.control.NumericEditField
        Vout_wsVLabel           matlab.ui.control.Label
        VowsEditField           matlab.ui.control.NumericEditField
        Pout_wsWLabel           matlab.ui.control.Label
        PowsEditField           matlab.ui.control.NumericEditField
        Vin_wsVLabel            matlab.ui.control.Label
        VinwsEditField          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        fn % Description
        Qe_arr
        Vin_rip = 10
        Vdrop = 9
        Vd = 1
        Vw = 0.5
        Vin_arr,Vout_arr,Iout_arr,Po_arr
        Vin_min,Vin_max
        Vout_min,Vout_max
        Nps
        Ln_pre,Qe_pre     % ln_pre = Lm/Lr
        fsmin,fsmax
        Racmin = 50
        Rac,fr,Cr,Lr,Lm,Zo
        CalOpt
    end

    methods (Access = private)
                
        function updataPlotDS(app)
            
            cla(app.DSAxes);
            
            % Get app user input
            app.Vin_min = app.VinminEditField.Value;
            app.Vin_max = app.VinmaxEditField.Value;
            app.Vout_min = app.VoutminEditField.Value;
            app.Vout_max = app.VoutmaxEditField.Value;
            
            app.Vout_arr = [app.Vo1EditField.Value,app.Vo2EditField.Value,...
                            app.Vo3EditField.Value,app.Vo4EditField.Value];
            app.Vin_arr = [app.Vin1EditField.Value,app.Vin2EditField.Value,...
                           app.Vin3EditField.Value,app.Vin4EditField.Value];            
            app.Po_arr = [app.Po1EditField.Value,app.Po2EditField.Value,...
                          app.Po3EditField.Value,app.Po4EditField.Value];
            app.Iout_arr = [app.Po_arr(1)/app.Vout_arr(1),app.Po_arr(2)/app.Vout_arr(2),...
                            app.Po_arr(3)/app.Vout_arr(3),app.Po_arr(4)/app.Vout_arr(4)];
            
            % Plot the working zoon
            hold(app.DSAxes,'on');
            grid(app.DSAxes,'minor');
            
            Io12_arr = [app.Iout_arr(2):0.1:app.Iout_arr(1)];
            k12 = (app.Vout_arr(2)-app.Vout_arr(1))/(app.Iout_arr(2)-app.Iout_arr(1));
            b12 = app.Vout_arr(2) - k12*app.Iout_arr(2);
            Vout12_arr = k12*Io12_arr+b12;
            plot(app.DSAxes,Io12_arr,Vout12_arr,'b','LineWidth',2);
            plot(app.DSAxes,app.Iout_arr(2),app.Vout_arr(2),'r*');
            text(app.DSAxes,0.9*app.Iout_arr(2),1.05*app.Vout_arr(2),'(Io2,Vo2)');
            
            Vo13_arr = [app.Vout_arr(3):app.Vout_arr(1)];
            Io13_arr = app.Iout_arr(3)*ones(1,length(Vo13_arr));
            plot(app.DSAxes,Io13_arr,Vo13_arr,'b','LineWidth',2);
            plot(app.DSAxes,app.Iout_arr(1),app.Vout_arr(1),'r*');
            text(app.DSAxes,0.8*app.Iout_arr(1),app.Vout_arr(1),'(Io1,Vo1)');
            
            Io2_arr = [0:0.1:app.Iout_arr(2)];
            Vout2_arr = app.Vout_arr(2)*ones(1,length(Io2_arr));
            plot(app.DSAxes,Io2_arr,Vout2_arr,'b','LineWidth',2);
            
            Io34_arr = [app.Iout_arr(4):0.1:app.Iout_arr(3)];
            k34 = (app.Vout_arr(3)-app.Vout_arr(4))/(app.Iout_arr(3)-app.Iout_arr(4));
            b34 = app.Vout_arr(3) - k34*app.Iout_arr(3);
            Vout34_arr = k34*Io34_arr+b34;
            plot(app.DSAxes,Io34_arr,Vout34_arr,'b','LineWidth',2);
            plot(app.DSAxes,app.Iout_arr(3),app.Vout_arr(3),'r*');
            text(app.DSAxes,0.8*app.Iout_arr(3),app.Vout_arr(3),'(Io3,Vo3)');
            
            Io4_arr = [0:app.Iout_arr(4)];
            Vout4_arr = app.Vout_arr(4)*ones(1,length(Io4_arr));
            plot(app.DSAxes,Io4_arr,Vout4_arr,'b','LineWidth',2);
            plot(app.DSAxes,app.Iout_arr(4),app.Vout_arr(4),'r*');
            text(app.DSAxes,0.9*app.Iout_arr(4),0.9*app.Vout_arr(4),'(Io4,Vo4)');
            
            ylim(app.DSAxes,[app.Vout_min-50,app.Vout_max+50]);
        end
        
        function ResTanCal(app)
            
            cla(app.OP1GCAxes);
            cla(app.OP2GCAxes);
            cla(app.OP3GCAxes);
            cla(app.OP4GCAxes);
            
            % Get app user input
            app.fn = linspace(0,2.5,1000);
            app.Nps = (app.Vin_max-app.Vdrop)/(app.Vout_max+2*app.Vd+app.Vw);
            app.TREditField.Value = app.Nps;
             
            app.Vout_arr = [app.Vo1EditField.Value,app.Vo2EditField.Value,...
                            app.Vo3EditField.Value,app.Vo4EditField.Value];
            app.Vin_arr = [app.Vin1EditField.Value,app.Vin2EditField.Value,...
                           app.Vin3EditField.Value,app.Vin4EditField.Value];            
            app.Po_arr = [app.Po1EditField.Value,app.Po2EditField.Value,...
                          app.Po3EditField.Value,app.Po4EditField.Value];
            app.Iout_arr = [app.Po_arr(1)/app.Vout_arr(1),app.Po_arr(2)/app.Vout_arr(2),...
                            app.Po_arr(3)/app.Vout_arr(3),app.Po_arr(4)/app.Vout_arr(4)];
                        
            app.Ln_pre = app.LnPreEditField.Value;
            app.Qe_pre = app.QePreEditField.Value;
            app.fsmin = app.FsminEditField.Value;
            app.fsmax = app.FsmaxEditField.Value;
            app.fr = app.frEditField.Value;
            
            app.CalOpt = app.CalOptionDD.Value;
            switch app.CalOpt
                case 'Option 1' 
                    i = 1;
                case 'Option 2' 
                    i = 2;
                case 'Option 3' 
                    i = 3;
                case 'Option 4' 
                    i = 4;    
            end
            
            app.Rac = 8*(app.Nps^2)*app.Vout_arr(i)/((pi^2)*app.Iout_arr(i));
            app.Cr = 1/(2*pi*app.Qe_pre*app.fr*app.Rac);    
            app.CrnFEditField.Value = app.Cr*10^6;    % with the unit of nF
            app.Lr = 1/((2*pi*app.fr)^2*app.Cr);
            app.LruHEditField.Value = app.Lr*10^3;    % with the unit of uH
            app.Lm = app.Ln_pre*app.Lr;
            app.LmuHEditField.Value = app.Lm*10^3;    % with the unit of uH
            app.Zo = sqrt(app.Lr/app.Cr);
           
            % plot option1
            Gmax = ((app.Vout_arr(1)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(1)-app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
            Gmin = ((app.Vout_arr(1)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(1)+app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));   
            Racmintmp = (8*app.Nps^2*app.Vout_arr(1))/(pi^2*app.Iout_arr(1));
            app.Racmin = min(app.Racmin,Racmintmp);
            Qemaxtmp = sqrt(app.Lr/app.Cr)/(Racmintmp);
            Mg = 1 ./ sqrt((1+1./app.Ln_pre-1./(app.Ln_pre.*app.fn.^2)).^2 ...      
                 + ((app.fn-1./app.fn).*Qemaxtmp).^2); % Plot Gain Curve
            hold(app.OP1GCAxes,'on');
            grid(app.OP1GCAxes,'minor');
            plot(app.OP1GCAxes,app.fn,Mg,'r','LineWidth',1.2); 
            plot(app.OP1GCAxes,app.fn,Gmax,'b--','LineWidth',1.2);
            plot(app.OP1GCAxes,app.fn,Gmin,'b--','LineWidth',1.2);
            
            % plot option2
            Gmax = ((app.Vout_arr(2)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(2)-app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
            Gmin = ((app.Vout_arr(2)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(2)+app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
            Racmintmp = (8*app.Nps^2*app.Vout_arr(2))/(pi^2*app.Iout_arr(2));
            app.Racmin = min(app.Racmin,Racmintmp);
            Qemaxtmp = sqrt(app.Lr/app.Cr)/(Racmintmp);
            Mg = 1 ./ sqrt((1+1./app.Ln_pre-1./(app.Ln_pre.*app.fn.^2)).^2 ...      
                 + ((app.fn-1./app.fn).*Qemaxtmp).^2); % Plot Gain Curve
            hold(app.OP2GCAxes,'on');
            grid(app.OP2GCAxes,'minor');
            plot(app.OP2GCAxes,app.fn,Mg,'r','LineWidth',1.2); 
            plot(app.OP2GCAxes,app.fn,Gmax,'b--','LineWidth',1.2);
            plot(app.OP2GCAxes,app.fn,Gmin,'b--','LineWidth',1.2);
            
            % plot option3
            Gmax = ((app.Vout_arr(3)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(3)-app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
            Gmin = ((app.Vout_arr(3)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(3)+app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
            Racmintmp = (8*app.Nps^2*app.Vout_arr(3))/(pi^2*app.Iout_arr(3));
            app.Racmin = min(app.Racmin,Racmintmp);
            Qemaxtmp = sqrt(app.Lr/app.Cr)/(Racmintmp);
            Mg = 1 ./ sqrt((1+1./app.Ln_pre-1./(app.Ln_pre.*app.fn.^2)).^2 ...      
                 + ((app.fn-1./app.fn).*Qemaxtmp).^2); % Plot Gain Curve
            hold(app.OP3GCAxes,'on');
            grid(app.OP3GCAxes,'minor');
            plot(app.OP3GCAxes,app.fn,Mg,'r','LineWidth',1.2); 
            plot(app.OP3GCAxes,app.fn,Gmax,'b--','LineWidth',1.2);
            plot(app.OP3GCAxes,app.fn,Gmin,'b--','LineWidth',1.2);
            
            % plot option4
            Gmax = ((app.Vout_arr(4)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(4)-app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
            Gmin = ((app.Vout_arr(4)+2*app.Vd+app.Vw)*app.Nps/...
                   (app.Vin_arr(4)+app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
            Racmintmp = (8*app.Nps^2*app.Vout_arr(4))/(pi^2*app.Iout_arr(4));
            app.Racmin = min(app.Racmin,Racmintmp);
            Qemaxtmp = sqrt(app.Lr/app.Cr)/(Racmintmp);
            Mg = 1 ./ sqrt((1+1./app.Ln_pre-1./(app.Ln_pre.*app.fn.^2)).^2 ...      
                 + ((app.fn-1./app.fn).*Qemaxtmp).^2); % Plot Gain Curve
            hold(app.OP4GCAxes,'on');
            grid(app.OP4GCAxes,'minor');
            plot(app.OP4GCAxes,app.fn,Mg,'r','LineWidth',1.2); 
            plot(app.OP4GCAxes,app.fn,Gmax,'b--','LineWidth',1.2);
            plot(app.OP4GCAxes,app.fn,Gmin,'b--','LineWidth',1.2);
                     
        end
        
        function updataPlotGC(app)
            
            cla(app.GainCurAxes);
            
            % Get app user input
            Vin_ws = app.VinwsEditField.Value;
            Vo_ws = app.VowsEditField.Value;
            Po_ws = app.PowsEditField.Value;
            Rac_max_ws = (8*app.Nps^2*Vo_ws^2)/(pi^2*Po_ws);
            Qe_max_ws = app.Zo/Rac_max_ws;
            Qe_ws_arr = Qe_max_ws*[0.1 0.3 0.5 0.8 1];
            Gmax_ws = ((Vo_ws+2*app.Vd+app.Vw)*app.Nps/...
                   (Vin_ws-app.Vin_rip-app.Vdrop))*ones(1,length(app.fn));
               
            hold(app.GainCurAxes,'on');
            grid(app.GainCurAxes,'minor');
            ylim(app.GainCurAxes,[0,2]);
            
            for k = 1:5
                Mg = 1 ./ sqrt((1+1./app.Ln_pre-1./(app.Ln_pre.*app.fn.^2)).^2 ...      
                         + ((app.fn-1./app.fn).*Qe_ws_arr(k)).^2); % Plot Gain Curve  
                % Create gain curve plot in proper axes
                if k ~= 5
                    plot(app.GainCurAxes,app.fn,Mg);               
                else
                    plot(app.GainCurAxes,app.fn,Mg,'r--','LineWidth',2);
                end     
            end
            legend(app.GainCurAxes,'Qe = 0.1*Qemax','Qe = 0.3*Qemax',...
                   'Qe = 0.5*Qemax','Qe = 0.8*Qemax','Qe = Qemax');
            plot(app.GainCurAxes,app.fn,Gmax_ws,'r--','LineWidth',1.2);   
            app.RacminEditField.Value = (Rac_max_ws);
            app.QemaxEditField.Value = Qe_max_ws;
                        
        end
        
    end


    methods (Access = private)

        % Callback function
        function PlotButtonPushed(app, event)
             updataPlotGC(app)
        end

        % Button pushed function: btnPlotDS
        function btnPlotDSButtonPushed(app, event)
            updataPlotDS(app)
        end

        % Button pushed function: btnCal
        function btnCalButtonPushed(app, event)
            ResTanCal(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AppWindow = uifigure;
            app.AppWindow.Name = 'UI Figure';
            setAutoResize(app, app.AppWindow, true)
            app.TabGroup = uitabgroup(app.AppWindow);
            app.Input = uitab(app.TabGroup);
            app.Input.Title = 'Input';
            app.Input.Units = 'pixels';
            app.DSsLabel = uilabel(app.Input);
            app.DSsLabel.Text = 'Design Sepcifications';
            app.Vin_minVEditFieldLabel = uilabel(app.Input);
            app.Vin_minVEditFieldLabel.Text = 'Vin_min(V)';
            app.VinminEditField = uieditfield(app.Input, 'numeric');
            app.VinminEditField.Limits = [600 650];
            app.VinminEditField.Value = 600;
            app.Vin_maxVEditFieldLabel = uilabel(app.Input);
            app.Vin_maxVEditFieldLabel.Text = 'Vin_max(V)';
            app.VinmaxEditField = uieditfield(app.Input, 'numeric');
            app.VinmaxEditField.Limits = [760 800];
            app.VinmaxEditField.Value = 790;
            app.Vout_1VLabel = uilabel(app.Input);
            app.Vout_1VLabel.Text = 'Vout_1(V)';
            app.Vo1EditField = uieditfield(app.Input, 'numeric');
            app.Vo1EditField.Limits = [200 800];
            app.Vo1EditField.Value = 400;
            app.Vout_2VLabel = uilabel(app.Input);
            app.Vout_2VLabel.Text = 'Vout_2(V)';
            app.Vo2EditField = uieditfield(app.Input, 'numeric');
            app.Vo2EditField.Limits = [200 800];
            app.Vo2EditField.Value = 450;
            app.Vout_3VLabel = uilabel(app.Input);
            app.Vout_3VLabel.Text = 'Vout_3(V)';
            app.Vo3EditField = uieditfield(app.Input, 'numeric');
            app.Vo3EditField.Limits = [200 800];
            app.Vo3EditField.Value = 350;
            app.Vout_4VLabel = uilabel(app.Input);
            app.Vout_4VLabel.Text = 'Vout_4(V)';
            app.Vo4EditField = uieditfield(app.Input, 'numeric');
            app.Vo4EditField.Limits = [200 800];
            app.Vo4EditField.Value = 200;
            app.Pout_1WLabel = uilabel(app.Input);
            app.Pout_1WLabel.Text = 'Pout_1(W)';
            app.Po1EditField = uieditfield(app.Input, 'numeric');
            app.Po1EditField.Limits = [7500 30000];
            app.Po1EditField.Value = 15000;
            app.Pout_2WLabel = uilabel(app.Input);
            app.Pout_2WLabel.Text = 'Pout_2(W)';
            app.Po2EditField = uieditfield(app.Input, 'numeric');
            app.Po2EditField.Limits = [7500 30000];
            app.Po2EditField.Value = 15000;
            app.Pout_3WLabel = uilabel(app.Input);
            app.Pout_3WLabel.Text = 'Pout_3(W)';
            app.Po3EditField = uieditfield(app.Input, 'numeric');
            app.Po3EditField.Limits = [7500 30000];
            app.Po3EditField.Value = 13125;
            app.Pout_4WLabel = uilabel(app.Input);
            app.Pout_4WLabel.Text = 'Pout_4(W)';
            app.Po4EditField = uieditfield(app.Input, 'numeric');
            app.Po4EditField.Limits = [3000 30000];
            app.Po4EditField.Value = 5000;
            app.DSAxes = uiaxes(app.Input);
            title(app.DSAxes, 'Output Voltage vs. Output Current');
            xlabel(app.DSAxes, 'Output Current (A)');
            ylabel(app.DSAxes, 'Output Voltage (V)');
            app.btnPlotDS = uibutton(app.Input, 'push');
            app.btnPlotDS.ButtonPushedFcn = createCallbackFcn(app, @btnPlotDSButtonPushed, true);
            app.btnPlotDS.Text = 'Plot';
            app.NoteLabel = uilabel(app.Input);
            app.NoteLabel.Text = 'Note: 1. Vout_1 is defined as maximum output voltage when the output current could reach maximum value.';
            app.NoteLabel_2 = uilabel(app.Input);
            app.NoteLabel_2.Text = '           2. Vout_2 is defined as maximum output voltage with a derated output current. ';
            app.NoteLabel_3 = uilabel(app.Input);
            app.NoteLabel_3.Text = '           3. Vout_3 is defined as minimum output voltage when the output current could reach maximum value.';
            app.NoteLabel_4 = uilabel(app.Input);
            app.NoteLabel_4.Text = '           4. Vout_4 is defined as minimum output voltage with a derated output current.';
            app.Vout_minVLabel = uilabel(app.Input);
            app.Vout_minVLabel.Text = 'Vout_min(V)';
            app.VoutminEditField = uieditfield(app.Input, 'numeric');
            app.VoutminEditField.Limits = [200 350];
            app.VoutminEditField.Value = 200;
            app.Vout_maxVLabel = uilabel(app.Input);
            app.Vout_maxVLabel.Text = 'Vout_max(V)';
            app.VoutmaxEditField = uieditfield(app.Input, 'numeric');
            app.VoutmaxEditField.Limits = [400 1000];
            app.VoutmaxEditField.Value = 450;
            app.Vin_1VLabel = uilabel(app.Input);
            app.Vin_1VLabel.Text = 'Vin_1(V)';
            app.Vin1EditField = uieditfield(app.Input, 'numeric');
            app.Vin1EditField.Limits = [600 800];
            app.Vin1EditField.Value = 760;
            app.Vin_2VLabel = uilabel(app.Input);
            app.Vin_2VLabel.Text = 'Vin_2(V)';
            app.Vin2EditField = uieditfield(app.Input, 'numeric');
            app.Vin2EditField.Limits = [600 800];
            app.Vin2EditField.Value = 790;
            app.Vin_3VLabel = uilabel(app.Input);
            app.Vin_3VLabel.Text = 'Vin_3(V)';
            app.Vin3EditField = uieditfield(app.Input, 'numeric');
            app.Vin3EditField.Limits = [600 800];
            app.Vin3EditField.Value = 660;
            app.Vin_4VLabel = uilabel(app.Input);
            app.Vin_4VLabel.Text = 'Vin_4(V)';
            app.Vin4EditField = uieditfield(app.Input, 'numeric');
            app.Vin4EditField.Limits = [600 800];
            app.Vin4EditField.Value = 600;
            app.ResTankTab = uitab(app.TabGroup);
            app.ResTankTab.Title = 'Res. Tank';
            app.ResTankTab.Units = 'pixels';
            app.IPsLabel = uilabel(app.ResTankTab);
            app.IPsLabel.Text = 'Internal Parameters';
            app.maxfskHzLabel = uilabel(app.ResTankTab);
            app.maxfskHzLabel.Text = 'max. fs (kHz)';
            app.FsmaxEditField = uieditfield(app.ResTankTab, 'numeric');
            app.FsmaxEditField.Limits = [150 280];
            app.FsmaxEditField.Value = 200;
            app.minfskHzLabel = uilabel(app.ResTankTab);
            app.minfskHzLabel.Text = 'min. fs (kHz)';
            app.FsminEditField = uieditfield(app.ResTankTab, 'numeric');
            app.FsminEditField.Limits = [50 120];
            app.FsminEditField.Value = 80;
            app.Ln_preLabel = uilabel(app.ResTankTab);
            app.Ln_preLabel.Text = 'Ln_pre';
            app.LnPreEditField = uieditfield(app.ResTankTab, 'numeric');
            app.LnPreEditField.Limits = [3 8];
            app.LnPreEditField.Value = 6;
            app.Qe_preLabel = uilabel(app.ResTankTab);
            app.Qe_preLabel.Text = 'Qe_pre';
            app.QePreEditField = uieditfield(app.ResTankTab, 'numeric');
            app.QePreEditField.Limits = [0.2 0.8];
            app.QePreEditField.Value = 0.6;
            app.RTPsLabel = uilabel(app.ResTankTab);
            app.RTPsLabel.Text = 'Res. Tank Parameters';
            app.turnsratioLabel = uilabel(app.ResTankTab);
            app.turnsratioLabel.Text = 'turns ratio';
            app.TREditField = uieditfield(app.ResTankTab, 'numeric');
            app.TREditField.Editable = 'off';
            app.btnCal = uibutton(app.ResTankTab, 'push');
            app.btnCal.ButtonPushedFcn = createCallbackFcn(app, @btnCalButtonPushed, true);
            app.btnCal.Text = 'Cal';
            app.frkHzLabel = uilabel(app.ResTankTab);
            app.frkHzLabel.Text = ' fr (kHz)';
            app.frEditField = uieditfield(app.ResTankTab, 'numeric');
            app.frEditField.Limits = [80 200];
            app.frEditField.Value = 100;
            app.CrnFLabel = uilabel(app.ResTankTab);
            app.CrnFLabel.Text = 'Cr (nF)';
            app.CrnFEditField = uieditfield(app.ResTankTab, 'numeric');
            app.CrnFEditField.Editable = 'off';
            app.LruHLabel = uilabel(app.ResTankTab);
            app.LruHLabel.Text = 'Lr (uH)';
            app.LruHEditField = uieditfield(app.ResTankTab, 'numeric');
            app.LruHEditField.Editable = 'off';
            app.LmuHEditFieldLabel = uilabel(app.ResTankTab);
            app.LmuHEditFieldLabel.Text = 'Lm (uH)';
            app.LmuHEditField = uieditfield(app.ResTankTab, 'numeric');
            app.LmuHEditField.Editable = 'off';
            app.CalOptionDropDownLabel = uilabel(app.ResTankTab);
            app.CalOptionDropDownLabel.Text = 'Cal Option';
            app.CalOptionDD = uidropdown(app.ResTankTab);
            app.OP1GCAxes = uiaxes(app.ResTankTab);
            title(app.OP1GCAxes, 'Option 1 Gain Curve');
            xlabel(app.OP1GCAxes, 'fn');
            ylabel(app.OP1GCAxes, 'Mg');
            app.OP2GCAxes = uiaxes(app.ResTankTab);
            title(app.OP2GCAxes, 'Option 2 Gain Curve');
            xlabel(app.OP2GCAxes, 'fn');
            ylabel(app.OP2GCAxes, 'Mg');
            app.OP3GCAxes = uiaxes(app.ResTankTab);
            title(app.OP3GCAxes, 'Option 3 Gain Curve');
            xlabel(app.OP3GCAxes, 'fn');
            ylabel(app.OP3GCAxes, 'Mg');
            app.OP4GCAxes = uiaxes(app.ResTankTab);
            title(app.OP4GCAxes, 'Option 4 Gain Curve');
            xlabel(app.OP4GCAxes, 'fn');
            ylabel(app.OP4GCAxes, 'Mg');
            app.GainCurveTab = uitab(app.TabGroup);
            app.GainCurveTab.Title = 'Gain Curve';
            app.GainCurveTab.Units = 'pixels';
            app.btnPlotGC = uibutton(app.GainCurveTab, 'push');
            app.btnPlotGC.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.btnPlotGC.Text = 'Plot';
            app.GainCurAxes = uiaxes(app.GainCurveTab);
            title(app.GainCurAxes, 'Gain Curve');
            xlabel(app.GainCurAxes, 'fn');
            ylabel(app.GainCurAxes, 'Mg');
            app.Qe_maxEditFieldLabel = uilabel(app.GainCurveTab);
            app.Qe_maxEditFieldLabel.Text = 'Qe_max';
            app.QemaxEditField = uieditfield(app.GainCurveTab, 'numeric');
            app.QemaxEditField.Editable = 'off';
            app.Rac_minLabel = uilabel(app.GainCurveTab);
            app.Rac_minLabel.Text = 'Rac_min';
            app.RacminEditField = uieditfield(app.GainCurveTab, 'numeric');
            app.RacminEditField.Editable = 'off';
            app.Vout_wsVLabel = uilabel(app.GainCurveTab);
            app.Vout_wsVLabel.Text = 'Vout_ws (V)';
            app.VowsEditField = uieditfield(app.GainCurveTab, 'numeric');
            app.VowsEditField.Limits = [200 800];
            app.VowsEditField.Value = 400;
            app.Pout_wsWLabel = uilabel(app.GainCurveTab);
            app.Pout_wsWLabel.Text = 'Pout_ws (W)';
            app.PowsEditField = uieditfield(app.GainCurveTab, 'numeric');
            app.PowsEditField.Limits = [7500 30000];
            app.PowsEditField.Value = 15000;
            app.Vin_wsVLabel = uilabel(app.GainCurveTab);
            app.Vin_wsVLabel.Text = 'Vin_ws (V)';
            app.VinwsEditField = uieditfield(app.GainCurveTab, 'numeric');
            app.VinwsEditField.Limits = [600 800];
            app.VinwsEditField.Value = 760;
        end
    end

    methods (Access = public)

        % Construct app
        function app = LLC_Calculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AppWindow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AppWindow)
        end
    end
end